classdef AtlasPainter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NextButton                   matlab.ui.control.Button
        PreviousButton               matlab.ui.control.Button
        ExportImagesButtonGroup      matlab.ui.container.ButtonGroup
        BrainalignedatlasesCheckBox  matlab.ui.control.CheckBox
        AllenalignedatlasesCheckBox  matlab.ui.control.CheckBox
        AllenalignedimagesCheckBox   matlab.ui.control.CheckBox
        ExportButton                 matlab.ui.control.Button
        ExportAllSectionsButton      matlab.ui.control.RadioButton
        ExportCurrentSectionButton   matlab.ui.control.RadioButton
        PressanykeyonthesectionimagetoselectanareaLabel  matlab.ui.control.Label
        PaintButton                  matlab.ui.control.Button
        AreaSelectionKnob            matlab.ui.control.DiscreteKnob
        AreaSelectionKnobLabel       matlab.ui.control.Label
        BlueRangeKnob                matlab.ui.control.Knob
        BlueLabel                    matlab.ui.control.Label
        GreenRangeKnob               matlab.ui.control.Knob
        GreenLabel                   matlab.ui.control.Label
        HistogramrangeLabel          matlab.ui.control.Label
        OpacityLabel                 matlab.ui.control.Label
        RedRangeKnob                 matlab.ui.control.Knob
        RedLabel                     matlab.ui.control.Label
        SavePaintingButton           matlab.ui.control.Button
        ValidPaintingButton          matlab.ui.control.Button
        sphinx_idEditField           matlab.ui.control.NumericEditField
        sphinx_idEditFieldLabel      matlab.ui.control.Label
        AreaTitle                    matlab.ui.control.Label
        ExtractValuesButtonGroup     matlab.ui.container.ButtonGroup
        DLSisolationButton           matlab.ui.control.Button
        ExporttoCSVCheckBox          matlab.ui.control.CheckBox
        ExporttoMatlabCheckBox       matlab.ui.control.CheckBox
        ExporttoExcelCheckBox        matlab.ui.control.CheckBox
        ExtractButton                matlab.ui.control.Button
        AllSectionsButton            matlab.ui.control.RadioButton
        CurrentSectionButton         matlab.ui.control.RadioButton
        AreacolorButtonGroup         matlab.ui.container.ButtonGroup
        BoundariesOnlyButton         matlab.ui.control.RadioButton
        AllenButton                  matlab.ui.control.RadioButton
        FastButton                   matlab.ui.control.RadioButton
        LoadButton                   matlab.ui.control.Button
        DrawModeButtonGroup          matlab.ui.container.ButtonGroup
        AssistHandButton             matlab.ui.control.ToggleButton
        PolygonButton                matlab.ui.control.ToggleButton
        EllipseButton                matlab.ui.control.ToggleButton
        FreeHandButton               matlab.ui.control.ToggleButton
        AreasDropDown                matlab.ui.control.DropDown
        AreasDropDownLabel           matlab.ui.control.Label
        SectionListDropDown          matlab.ui.control.DropDown
        SectionListDropDownLabel     matlab.ui.control.Label
        AtlasAlphaKnob               matlab.ui.control.Knob
        AtlasKnobLabel               matlab.ui.control.Label
        SectionAlphaKnob             matlab.ui.control.Knob
        SectionKnobLabel             matlab.ui.control.Label
        ColorKnob                    matlab.ui.control.DiscreteKnob
        ColorknobLabel               matlab.ui.control.Label
        MouseEditField               matlab.ui.control.EditField
        MouseEditFieldLabel          matlab.ui.control.Label
        BrowseMiceButton             matlab.ui.control.Button
        BrowseAllenCCFButton         matlab.ui.control.Button
        AllenBrainEditField          matlab.ui.control.EditField
        AllenBrainEditFieldLabel     matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        st % Structure Tree from Allen CCF
        
        histology_ccf % Structure with all CCF formatted sections
        histology_ccf_alignment % Transform from CCF to section
        histology_ccf_history % Store previouses atlases once modif have been done
        
        section_nb % nb of current section in folder (dropdown section menu defined) %should change
        
        curr_img % Section raw image
        curr_atl % Atlas raw image
        
        disp_img % Section image to display
        disp_atl % Atlas image to display
                
        histology_aligned_av_slices % Cell containing section adjusted atlas
        histology_aligned_av_slices_RGB % current RGB aligned atlas
        histology_ccf_RGB % CCF formatted atlas RGB image from hexadecimal Allen CCF code

        ax_h % Axe of display

        img_h % handle to current section image
        atlas_h % handle to current atlas image
        curr_roi % roi object if something has been drawn
        rounded_roi_position % results of the rounding of drawn ROI position 
    end
      

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CreateAxeOnStartup(app)
%             units = get(groot, 'Units'); % backup Units
%             set(groot, 'Units', 'pixels');
%             app.UIFigure.Position = get(groot, 'ScreenSize');
%             set(groot, 'Units', 'normalized'); % restore original units
            
%             app.UIAxes = uiaxes('Parent',app.UIFigure,'Position',[0.1,0.1,0.8,0.8],'ButtonDownFcn',@GetClickedRegion,'Units',"normalized");
        end

        % Button pushed function: LoadButton
        function Initialize(app, event)
            % Load CCF atlas
%             app.AllenBrainEditField.Value = 'D:\00 Track Location\Allen Brain Mouse\';
%             tv = readNPY([app.AllenBrainEditField.Value filesep 'template_volume_10um.npy']);
%             av = readNPY([app.AllenBrainEditField.Value filesep 'annotation_volume_10um_by_index.npy']);


            cla(app.UIAxes)
            
            waitload = waitbar(0,'Loading your files');

            
            % Load corresponding CCF slices
            ccf_slice_fn = [app.MouseEditField.Value filesep 'histology_ccf.mat'];
            load(ccf_slice_fn);
            app.histology_ccf = histology_ccf;
            app.SectionListDropDown.Items = cellstr(num2str([1:length(histology_ccf)].'));
                        
            
            % Load histology/CCF alignment
            ccf_alignment_fn = [app.MouseEditField.Value filesep 'atlas2histology_tform.mat'];
            load(ccf_alignment_fn);
            app.histology_ccf_alignment = atlas2histology_tform;
            
            
            % Remove, default open on GPe example
            app.section_nb = str2double(app.SectionListDropDown.Value);
            
            
%             app.SectionListDropDown.Value = num2str(app.section_nb);
            s=0;
            nb_sections = length(histology_ccf);
            app.histology_aligned_av_slices = [];
            
            close(waitload)
            waitloadh = waitbar(0,strcat('Computing Section shaped atlases',num2str(s), ' of ', num2str(nb_sections)));

            for s=1:nb_sections
                waitbar(s/nb_sections,waitloadh,strcat('Computing Section shaped atlases: ',num2str(s), ' of ', num2str(nb_sections)));

                
                app.section_nb = s;
                
                app.curr_img = imread(fullfile(app.MouseEditField.Value, strcat(num2str(app.section_nb),'.tif')));     
%                 app.SectionListDropDown.Items = cellstr(num2str([1:length(histology_ccf)].'));
                
                 % Computing transform needed to shape atlas to the section
                tform = affine2d;
                tform.T = app.histology_ccf_alignment{s};   
                tform_size = imref2d([size(app.curr_img,1),size(app.curr_img,2)]);           
                
                % Computing atlas shaped to the section
                app.histology_aligned_av_slices{s} = zeros([size(app.curr_img,1),size(app.curr_img,2)]);

                app.histology_aligned_av_slices{s} = ...
                    imwarp(app.histology_ccf(s).av_slices,tform,'nearest','OutputView',tform_size);       
            end
            app.SectionListDropDown.Value = cellstr(num2str(s));
            % Area list creation and display
            app.st = loadStructureTree([app.AllenBrainEditField.Value filesep 'structure_tree_safe_2017.csv']);
            
%             AreaSelect = app.AreacolorButtonGroup.SelectedObject;
            close(waitloadh)
            
            app.SectionListDropDown.Value = app.SectionListDropDown.Items{1};
            UpdateDisp(app,event)
        end

        % Button down function: AreacolorButtonGroup
        function ConvertToAllenColors(app, event)
            selectedButton = app.AreacolorButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Allen')
                nb_sections = length(app.SectionListDropDown.Items);
                
                if isempty(app.histology_aligned_av_slices_RGB)
                    for s=1:nb_sections
                        app.histology_ccf_RGB{s} = [];
                        
                        tform = affine2d;
                        tform.T = app.histology_ccf_alignment{s};   
                        tform_size = imref2d([size(app.curr_img,1),size(app.curr_img,2)]);
                        
                        % Function that is retrieving Hexadecimal color code for
                        % each pixel
                        app.histology_ccf_RGB{s} = av_to_RGB_fast(app.st, app.histology_ccf,s);
                        app.histology_aligned_av_slices_RGB{s} = ...
                            imwarp(app.histology_ccf_RGB{s},tform,'nearest','OutputView',tform_size);
                    end
                end
%                 app.img_h = image(app.UIAxes,app.curr_img,'AlphaData',app.SectionAlphaKnob.Value/100,'Parent',app.UIAxes);
%                 hold(app.UIAxes,'on')
%                 
%                 atlas_alpha_data=ones(size(app.aligned_RGB{app.section_nb}));
%                 atlas_alpha_data(app.disp_atl==0)=0;
%                 atlas_alpha_data(app.disp_atl==1)=0;
%                 atlas_alpha_data = atlas_alpha_data*app.AtlasAlphaKnob.Value/100;
%                 
% %                 image(app.UIAxes,app.histology_aligned_av_slices{app.section_nb},'AlphaData',atlas_alpha_data)
%                 app.atlas_h = image(app.UIAxes,app.aligned_RGB{app.section_nb},'AlphaData',app.AtlasAlphaKnob.Value/100,'Parent',app.UIAxes);
%     
%                
            elseif strcmp(selectedButton.Text,'Fast')
%                 
%                 app.img_h = image(app.UIAxes,app.curr_img,'AlphaData',app.SectionAlphaKnob.Value/100,'Parent',app.UIAxes);
%                 hold(app.UIAxes,'on')
%                 app.atlas_h = image(app.UIAxes,app.histology_aligned_av_slices{app.section_nb},'AlphaData',app.AtlasAlphaKnob.Value/100,'Parent',app.UIAxes);
%     
            end
            
            UpdateDisp(app,event)
        end

        % Button pushed function: ExtractButton
        function ExtractFcn(app, event)
            selectedButton = app.ExtractValuesButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'All Sections')
                section_idx = [1:length(app.histology_aligned_av_slices)];
            else
                section_idx = app.section_nb;                
            end
            
            Side = {'_L','_R'};
            nb_sections = length(app.SectionListDropDown.Items);
            table_headers = {};
            table_headers = {'sphinx_id' 'safe_name' 'Total_L' 'Total_R'};
            for s=1:nb_sections
                table_headers = [table_headers strcat(num2str(s),Side(1))];
                table_headers = [table_headers strcat(num2str(s),Side(2))];

            end
%                 TemplateTable = table();
%                 
%                 TemplateTable(1,:) = table(cell(ones(1,length(table_headers))));
%                 TemplateTable.sphinx_id(1:length(app.st.sphinx_id))=app.st.sphinx_id;
%                 TemplateTable.safe_name(1:length(app.st.safe_name))=app.st.safe_name;   
%                 TemplateTable.Properties.VariableNames = table_headers;
%                 TemplateTable(1,:) = num2cell(1:76);
%                 TemplateTable(2,:) = num2cell(1:76);
%                 TemplateTable(2,:) = [];
% 
%                 TemplateTable(1,3:end)=[];
%                

            % Init of results structure
            for c = 1:3
                for s=  section_idx
                    results.color(c).section(s).nb_pixels = double(app.st.sphinx_id);
                    results.color(c).section(s).mean_fluo = double(app.st.sphinx_id);
                    results.color(c).section(s).median_fluo = double(app.st.sphinx_id);
                    results.color(c).section(s).sum_fluo = double(app.st.sphinx_id);
                    results.color(c).section(s).std_fluo = double(app.st.sphinx_id);
                    results.color(c).section(s).prop_min_fluo = double(app.st.sphinx_id);
                    results.color(c).section(s).prop_max_fluo = double(app.st.sphinx_id);
                    
                    results.color(c).section(s).nb_pixels(1:end,2:3) = nan(size(app.st.sphinx_id,1),2);
                    results.color(c).section(s).mean_fluo(:,2:3) = nan(size(app.st.sphinx_id,1),2);
                    results.color(c).section(s).median_fluo(:,2:3) = nan(size(app.st.sphinx_id,1),2);
                    results.color(c).section(s).sum_fluo(:,2:3) = nan(size(app.st.sphinx_id,1),2);
                    results.color(c).section(s).std_fluo(:,2:3) = nan(size(app.st.sphinx_id,1),2);
                    results.color(c).section(s).prop_min_fluo(:,2:3) = nan(size(app.st.sphinx_id,1),2);
                    results.color(c).section(s).prop_max_fluo(:,2:3) = nan(size(app.st.sphinx_id,1),2);
                end
            end
            
           wh = waitbar(0,strcat('Densitometry section',num2str(1), ' of ', num2str(nb_sections)));
 
            for s = section_idx
                waitbar(s/length(section_idx),wh,strcat('Quantif section',num2str(s), ' of ', num2str(nb_sections)));

                app.curr_img = double(imread(fullfile(app.MouseEditField.Value, strcat(num2str(s),'.tif'))));
                app.curr_atl = app.histology_aligned_av_slices{s};
%                     histology_aligned_av_slices
%                     tform = affine2d;
%                     tform.T = app.histology_ccf_alignment{s};   
%                     tform_size = imref2d([size(app.curr_img,1),size(app.curr_img,2)]);
%                     
%                     app.histology_aligned_av_slices{s} = ...
%                     imwarp(app.curr_atl,tform,'nearest','OutputView',tform_size);
        
                unique_sphinx_id = unique(app.histology_aligned_av_slices{s});
                unique_sphinx_id = unique_sphinx_id(~isnan(unique_sphinx_id));
                unique_sphinx_id = unique_sphinx_id(unique_sphinx_id>1);
%                     


                % to implement: thresholded fluo, put
                % thresholds in main gui
%                                 results.color(c).section(s).hemi(h).prop_over_thresh_fluo = app.st.sphinx_id;
                
                
                for a = 1:length(unique_sphinx_id)
%                     waitbar(a/length(unique_sphinx_id),wh,strcat('Quantif section',num2str(s), ' of ', num2str(nb_sections)));
                 
                    % determine where are the pixels of the area in
                    % question in the atlas and make a mask to
                    % calculate fluo values
                    section_area_mask = [];
                    section_area_mask.logic = app.histology_aligned_av_slices{s} == unique_sphinx_id(a);
                    
                    % Break mask into Left hemisphere (1) and Right hemisphere (2)
                    % (based on half width of centered atlas)

                    section_area_mask.hemi(1).logic=section_area_mask.logic;
                    section_area_mask.hemi(1).logic(:,1:floor(size(app.histology_aligned_av_slices{s},2)/2)) = 0;
                    section_area_mask.hemi(2).logic=section_area_mask.logic;
                    section_area_mask.hemi(2).logic(:,ceil(size(app.histology_aligned_av_slices{s},2):end/2)) = 0;


                    for c=1:3
                        for h = 1:2
                            % Create one mask for each channel, zero-ing
                            % other channels, for each hemisphere
                            section_area_mask.hemi(h).color(c).logic(:,:,1:3) = zeros([size(section_area_mask.hemi(h).logic) 3]);
                            section_area_mask.hemi(h).color(c).logic(:,:,c) = [section_area_mask.hemi(h).logic];
                            section_area_mask.hemi(h).color(c).logic = logical(section_area_mask.hemi(h).color(c).logic);
                    
                            % extract and compute results
                            results.color(c).section(s).nb_pixels(unique_sphinx_id(a),h+1) = sum(sum(sum(section_area_mask.hemi(h).color(c).logic)));
                            results.color(c).section(s).mean_fluo(unique_sphinx_id(a),h+1) = nanmean(app.curr_img(section_area_mask.hemi(h).color(c).logic),'all');
                            results.color(c).section(s).median_fluo(unique_sphinx_id(a),h+1) = nanmedian(app.curr_img(section_area_mask.hemi(h).color(c).logic),'all');
                            results.color(c).section(s).sum_fluo(unique_sphinx_id(a),h+1) = sum(sum(sum(app.curr_img(section_area_mask.hemi(h).color(c).logic))));
                            results.color(c).section(s).std_fluo(unique_sphinx_id(a),h+1) = nanstd(double(app.curr_img(section_area_mask.hemi(h).color(c).logic)),0,'all');
                            
                            results.color(c).section(s).prop_min_fluo(unique_sphinx_id(a),h+1) = numel(find(app.curr_img(section_area_mask.hemi(h).color(c).logic) == 0)) ...
                                / results.color(c).section(s).nb_pixels(unique_sphinx_id(a),h+1);
                            % Forced to 16bit max (2^16 -1 = 65535)
                            results.color(c).section(s).prop_max_fluo(unique_sphinx_id(a),h+1) = numel(find(app.curr_img(section_area_mask.hemi(h).color(c).logic) == 65535)) ...
                                / results.color(c).section(s).nb_pixels(unique_sphinx_id(a),h+1);
                            
                            
                        end
                    end
                % end of area    
                end

            % end of section
            end
                        
            close(wh)
            
            if app.ExporttoMatlabCheckBox.Value == 1
                currfolder = app.MouseEditField.Value;
                
                save(fullfile(currfolder,'Export_Fluo_Count.mat'),'results','section_area_mask','-v7.3')
            end
            
            if app.ExporttoExcelCheckBox.Value == 1
                currfolder = app.MouseEditField.Value;
                
                sheet_suffix = {'Nb Pixels', 'Mean Fluo', 'Median Fluo','Sum Fluo','STD Fluo','Prop min Fluo','Prop max Fluo'};
                mat_names = {'temp_nb_pixels_mat','temp_mean_fluo_mat','temp_median_fluo_mat','temp_sum_fluo_mat','temp_std_fluo_mat','temp_prop_min_fluo_mat','temp_prop_max_fluo_mat'};
                
                ewh = waitbar(c/3,strcat('Exporting results section:',num2str(s)));

                for c = 1:length(results.color) 
                        waitbar(c/3,ewh,strcat('Exporting results section:',num2str(s)));
                        temp_nb_pixels_mat= nan(height(app.st),nb_sections*2+4); % matrix creations, one row per atlas area, one column by section by hemisphere plus sphinx_id + safe_name + total_L + total_R
                        temp_mean_fluo_mat= nan(height(app.st),nb_sections*2+4); % matrix creations, one row per atlas area, one column by section by hemisphere plus sphinx_id + safe_name + total_L + total_R
                        temp_median_fluo_mat= nan(height(app.st),nb_sections*2+4); % matrix creations, one row per atlas area, one column by section by hemisphere plus sphinx_id + safe_name + total_L + total_R
                        temp_sum_fluo_mat= nan(height(app.st),nb_sections*2+4); % matrix creations, one row per atlas area, one column by section by hemisphere plus sphinx_id + safe_name + total_L + total_R
                        temp_std_fluo_mat= nan(height(app.st),nb_sections*2+4); % matrix creations, one row per atlas area, one column by section by hemisphere plus sphinx_id + safe_name + total_L + total_R
                        temp_prop_min_fluo_mat= nan(height(app.st),nb_sections*2+4); % matrix creations, one row per atlas area, one column by section by hemisphere plus sphinx_id + safe_name + total_L + total_R
                        temp_prop_max_fluo_mat= nan(height(app.st),nb_sections*2+4); % matrix creations, one row per atlas area, one column by section by hemisphere plus sphinx_id + safe_name + total_L + total_R
                        
                    for s = section_idx
                        temp_nb_pixels_mat(:,s*2+3:s*2+4) = results.color(c).section(s).nb_pixels(:,2:3);
                        temp_mean_fluo_mat(:,s*2+3:s*2+4) = results.color(c).section(s).mean_fluo(:,2:3);
                        temp_median_fluo_mat(:,s*2+3:s*2+4) = results.color(c).section(s).median_fluo(:,2:3);
                        temp_sum_fluo_mat(:,s*2+3:s*2+4) = results.color(c).section(s).sum_fluo(:,2:3);
                        temp_std_fluo_mat(:,s*2+3:s*2+4) = results.color(c).section(s).std_fluo(:,2:3);
                        temp_prop_min_fluo_mat(:,s*2+3:s*2+4) = results.color(c).section(s).prop_min_fluo(:,2:3);
                        temp_prop_max_fluo_mat(:,s*2+3:s*2+4) = results.color(c).section(s).prop_max_fluo(:,2:3);      
                    
                    end
                    
                    temp_nb_pixels_mat(:,3) = sum(temp_nb_pixels_mat(:,5:2:end),2,'omitnan');% ./ sum(temp_nb_pixels_mat(:,5:2:end),2,'omitnan'); % Left hemisphere
                    temp_nb_pixels_mat(:,4) = sum(temp_nb_pixels_mat(:,6:2:end),2,'omitnan');% ./ sum(temp_nb_pixels_mat(:,6:2:end),2,'omitnan'); % Right hemisphere
                    
                    temp_mean_fluo_mat(:,3) = sum(temp_sum_fluo_mat(:,5:2:end),2,'omitnan') ./ temp_nb_pixels_mat(:,3);
                    temp_mean_fluo_mat(:,4) = sum(temp_mean_fluo_mat(:,6:2:end),2,'omitnan') ./ temp_nb_pixels_mat(:,4);
                    
                    temp_median_fluo_mat(:,3) = sum(temp_mean_fluo_mat(:,5:2:end),2,'omitnan') ./ temp_nb_pixels_mat(:,3);
                    temp_median_fluo_mat(:,4) = sum(temp_mean_fluo_mat(:,6:2:end),2,'omitnan') ./ temp_nb_pixels_mat(:,4);
                    
                    temp_sum_fluo_mat(:,3) = sum(temp_mean_fluo_mat(:,5:2:end),2,'omitnan') ./ temp_nb_pixels_mat(:,3);
                    temp_sum_fluo_mat(:,4) = sum(temp_mean_fluo_mat(:,6:2:end),2,'omitnan') ./ temp_nb_pixels_mat(:,4);
                    
                    temp_std_fluo_mat(:,3) = sum(temp_mean_fluo_mat(:,5:2:end),2,'omitnan') ./ temp_nb_pixels_mat(:,3);
                    temp_std_fluo_mat(:,4) = sum(temp_mean_fluo_mat(:,6:2:end),2,'omitnan') ./ temp_nb_pixels_mat(:,4);
                    
                    temp_prop_min_fluo_mat(:,3) = sum(temp_mean_fluo_mat(:,5:2:end),2,'omitnan') ./ temp_nb_pixels_mat(:,3);
                    temp_prop_min_fluo_mat(:,4) = sum(temp_mean_fluo_mat(:,6:2:end),2,'omitnan') ./ temp_nb_pixels_mat(:,4);
                    
                    temp_prop_max_fluo_mat(:,3) = sum(temp_mean_fluo_mat(:,5:2:end),2,'omitnan') ./ temp_nb_pixels_mat(:,3);
                    temp_prop_max_fluo_mat(:,4) = sum(temp_mean_fluo_mat(:,6:2:end),2,'omitnan') ./ temp_nb_pixels_mat(:,4);
                    
                    for r=1:length(sheet_suffix)
                        sheet_name = strcat('C',num2str(c),'_',sheet_suffix{r});
                        
                        temptable = table();
                        temptable(1:size(eval(mat_names{r}),1),1:size(eval(mat_names{r}),2)) = num2cell(eval(mat_names{r}));
                        temptable(:,1)=num2cell(app.st.sphinx_id);   
                        temptable.Properties.VariableNames=table_headers;
                        temptable.Properties.RowNames=app.st.safe_name;
                        temptable.safe_name=app.st.safe_name;
                        
                        writetable(temptable,fullfile(app.MouseEditField.Value,'Export_Fluo_Count.xlsx'),'Sheet',sheet_name)
                    end
                end
                close(ewh)
                % After all Channel have run
                                                   
            end

        end

        % Button pushed function: PaintButton
        function Paint(app, event)
            
            % Delete previous ROI
            app.curr_roi = [];
            previous_roi = findobj(app.UIAxes,'Type','images.roi');
            delete(previous_roi)   
            
            % Retrieve current atlas code
            paintcode = app.sphinx_idEditField.Value;
            
            % keep value clicked at paint in editField UserData 
            app.sphinx_idEditField.UserData = paintcode;
            
            % allow blanking with code 0
            if paintcode > 0
                rgb_color = hex2rgb(app.st.color_hex_triplet(paintcode));
            else
                rgb_color = [0 0 0];
            end
                
             % Choose ROI tool to draw in the selected area color
            if app.FreeHandButton.Value == 1

                app.curr_roi = drawfreehand(app.UIAxes,'Color', ...
                    rgb_color); 

            elseif app.AssistHandButton.Value == 1

                app.curr_roi = drawassisted(app.img_h,'Color', ...
                    rgb_color); 
                
            elseif app.EllipseButton.Value == 1

                app.curr_roi = Ellipse(app.UIAxes,'Color', ...
                   rgb_color); 
                
            elseif app.PolygonButton.Value == 1   

                 app.curr_roi = Polygon(app.UIAxes,'Color', ...
                    rgb_color);                           
            end
            
%            app.curr_roi = draw(app.UIAxes);

            try
                if app.EllipseButton.Value
                    app.rounded_roi_position = [];
                    app.rounded_roi_position = round(app.curr_roi.Vertices);
                elseif app.FreeHandButton.Value || app.PolygonButton.Value ||  app.AssistHandButton.Value
                    app.rounded_roi_position = [];
    
                    app.rounded_roi_position=round(app.curr_roi.Position);
                end
            
                app.ValidPaintingButton.BackgroundColor = [0 0.8 0];
            catch
                delete(app.curr_roi)
            end
            
        end

        % Window button motion function: UIFigure
        function mousehover(app, event)
            % Display area of atlas on mouse hover

%             % Get guidata
%             gui_data = guidata(gui_fig);
            if ~isempty(app.histology_aligned_av_slices)

            % Get mouse position
            mouse_position = get(app.UIAxes,'CurrentPoint');
            mouse_x = round(mouse_position(1,1));
            mouse_y = round(mouse_position(1,2));
            
                curr_av_slice_warp = app.histology_aligned_av_slices{app.section_nb};
                
                % Don't use if mouse out of bounds
                if ~ismember(mouse_x,1:size(curr_av_slice_warp,2)) || ...
                        ~ismember(mouse_y,1:size(curr_av_slice_warp,1))
                    return
                end
                    
                curr_av = curr_av_slice_warp(mouse_y,mouse_x);
                
                % Don't use if AV = 0
                if curr_av == 0
                    return
                end
                
                % Grab area name and set title
                curr_area_name = app.st.safe_name(curr_av);
                app.AreaTitle.Text =curr_area_name;
            end
            
            
        end

        % Window key press function: UIFigure
        function PickAreaOnClick(app, event)
            if ~isempty(app.histology_aligned_av_slices)
                % Get mouse position
                mouse_position = get(app.UIAxes,'CurrentPoint');
                mouse_x = round(mouse_position(1,1));
                mouse_y = round(mouse_position(1,2));
                
                curr_av_slice_warp = app.histology_aligned_av_slices{app.section_nb};
                
                % Don't use if mouse out of bounds
                if ~ismember(mouse_x,1:size(curr_av_slice_warp,2)) || ...
                        ~ismember(mouse_y,1:size(curr_av_slice_warp,1))
                    return
                end
                    
                curr_av = curr_av_slice_warp(mouse_y,mouse_x);
                
                % Don't use if AV = 0
                if curr_av == 0
                    return
                end
                
                % Grab area name and set title
                curr_area_name = app.st.safe_name(curr_av);
%                     app.AreaDropDown.Value =curr_area_name;
                app.PaintButton.Text = strcat('Draw  : ',num2str(curr_av));
                app.PaintButton.BackgroundColor = hex2rgb(app.st.color_hex_triplet(curr_av));
                app.sphinx_idEditField.Value = curr_av;
                app.AreasDropDown.Value = app.st.safe_name(curr_av);
            end
        end

        % Value changed function: SectionListDropDown
        function LoadSection(app, event)
            app.section_nb = str2double(app.SectionListDropDown.Value);
            
            app.curr_img = imread(fullfile(app.MouseEditField.Value, strcat(num2str(app.section_nb),'.tif')));
            app.curr_atl = app.histology_aligned_av_slices{app.section_nb};
%             UpdateDisp(app, event)
        end

        % Callback function: AreaSelectionKnob, AreacolorButtonGroup, 
        % AtlasAlphaKnob, BlueRangeKnob, ColorKnob, GreenRangeKnob, 
        % RedRangeKnob, SectionAlphaKnob
        function UpdateDisp(app, event)
            app.section_nb = str2double(app.SectionListDropDown.Value);
            LoadSection(app, event)
            
            cla(app.UIAxes)
            app.disp_img = app.curr_img;
            
            % select the way to display the atlas
            if app.FastButton.Value == 1 
                % Raw sphinx_id
                app.curr_atl = app.histology_aligned_av_slices{app.section_nb};

            elseif app.AllenButton.Value == 1 
                % Allen RGB version
                if isempty(app.histology_aligned_av_slices_RGB)
                    ConvertToAllenColors(app,event); % Possibly unnecessary
                end
                app.curr_atl = app.histology_aligned_av_slices_RGB{app.section_nb};
                
            elseif app.BoundariesOnlyButton.Value == 1 
                % Boundaries 
                
                %% TODO: add check to compute Allen color atlases first
                app.curr_atl = app.histology_aligned_av_slices{app.section_nb};
                
                % Set up histology-aligned atlas overlay
                % (and make it invisible to mouse clicks)
                histology_aligned_atlas_boundaries_init = ...
                zeros(size(app.curr_img,1),size(app.curr_img,2));

            end
            
            
            switch app.ColorKnob.Value
                
                % turning down unwanted channels
                case 'All'
                    app.disp_img = app.disp_img;
                case 'Red'
                    app.disp_img(:,:,2:3)=zeros(size(app.disp_img(:,:,2:3)));
                case 'Green'
                    app.disp_img(:,:,[1 3])=zeros(size(app.disp_img(:,:,[1 3])));                   
                case 'Blue'
                    app.disp_img(:,:,1:2)=zeros(size(app.disp_img(:,:,1:2)));
                    
            end
            
            % Converting to double to prevent scaling factor rounding error
            app.disp_img= double(app.disp_img);

            % constructing mask and scaling pixel values
            rangemask = ones(size(app.disp_img(:,:,1)))./(app.RedRangeKnob.Value/100);
            app.disp_img(:,:,1) = app.disp_img(:,:,1) .* rangemask;

            rangemask = ones(size(app.disp_img(:,:,2)))./(app.GreenRangeKnob.Value/100);
            app.disp_img(:,:,2) = app.disp_img(:,:,2) .* rangemask;            

            rangemask = ones(size(app.disp_img(:,:,3)))./(app.BlueRangeKnob.Value/100);
            app.disp_img(:,:,3) = app.disp_img(:,:,3) .* rangemask;
            
%             app.disp_img = double(app.disp_img) .* rangemask;
             
            max_pix_value = 255; % 16 bit value max, to implement better and check
            % relevel where values are above max
            
            
            app.disp_img(app.disp_img > max_pix_value) = max_pix_value.*ones(size(app.disp_img(app.disp_img > max_pix_value)));
            
            app.disp_img = uint8(app.disp_img);
            
            app.img_h = image(app.disp_img,'AlphaData',app.SectionAlphaKnob.Value/100,'Parent',app.UIAxes);
            hold(app.UIAxes,'on')
            
            if app.BoundariesOnlyButton.Value == 0 
                app.disp_atl = app.curr_atl;
                
                atlas_alpha_data=ones([size(app.disp_atl,1) size(app.disp_atl,2)]);

%                 if app.FastButton.Value == 1 %RGB mat
                    atlas_alpha_data(app.histology_aligned_av_slices{app.section_nb}==0)=0;
                    atlas_alpha_data(app.histology_aligned_av_slices{app.section_nb}==1)=0; % might be prob for boundaries
%                 end
                atlas_alpha_data = atlas_alpha_data*app.AtlasAlphaKnob.Value/100;
                           
                app.atlas_h = image(app.disp_atl,'AlphaData',atlas_alpha_data,'Parent',app.UIAxes);
                
            else
                % plotting border
                app.atlas_h = ...
                imagesc(histology_aligned_atlas_boundaries_init,'Parent',app.UIAxes, ...
                  'AlphaData',histology_aligned_atlas_boundaries_init,'PickableParts','none');
                app.curr_atl = round(conv2(app.histology_aligned_av_slices{app.section_nb} ...
                    ,ones(3)./9,'same')) ~= app.histology_aligned_av_slices{app.section_nb};
    
                % fill image and apply transparency
                set(app.atlas_h, ...
                    'CData',app.curr_atl, ...
                    'AlphaData',app.curr_atl*(app.AtlasAlphaKnob.Value/100));    
    
                
            end
            unique_sphinx_id = unique(app.disp_atl);
            unique_sphinx_id = unique_sphinx_id(~isnan(unique_sphinx_id));
            unique_sphinx_id = unique_sphinx_id(unique_sphinx_id>0);
            
                        
%             st_code_idx = find(app.st.sphinx_id==unique_sphinx_id);
            switch app.AreaSelectionKnob.Value
                case 'Visible only'
                    app.AreasDropDown.Items = app.st.safe_name(unique_sphinx_id);
                case 'All'      
                    app.AreasDropDown.Items = app.st.safe_name;
                case 'Custom'
            end


        end

        % Value changed function: AreasDropDown
        function PickAreaInMenu(app, event)
            value = app.AreasDropDown.Value;
            % Grab area name and set title
            curr_av = app.st.sphinx_id(strcmp(app.st.safe_name, value));
            %                     app.AreaDropDown.Value =curr_area_name;
            app.PaintButton.Text = strcat('Draw  : ',num2str(curr_av));
            app.PaintButton.BackgroundColor = hex2rgb(app.st.color_hex_triplet(curr_av));
            app.sphinx_idEditField.Value = double(curr_av);

            % Grab area name and set title
            curr_area_name = app.st.safe_name(curr_av);
            app.AreaTitle.Text =curr_area_name;
        end

        % Button pushed function: ValidPaintingButton
        function ValidPainting(app, event)

%             previous_roi = findobj(app.UIAxes,'Type','images.roi');
            % retrieve paintcode stored in userdata arbitrarily
            paintcode = app.sphinx_idEditField.UserData;
            
            % allow blanking with code 0
            if paintcode > 0
                rgb_color = hex2rgb(app.st.color_hex_triplet(paintcode));
            else
                rgb_color = [0 0 0];
            end
            app.rounded_roi_position
            paintmask = poly2mask(app.rounded_roi_position(:,1),app.rounded_roi_position(:,2),size(app.curr_atl,1),size(app.curr_atl,2));
            
            app.histology_aligned_av_slices{app.section_nb}(paintmask)=paintcode;
            
            % Compute also real atlas color if allen real color atlases has
            % been computed (button Allen in area color)
            if ~isempty(app.histology_aligned_av_slices_RGB)
                colortemp=[];
                for c = 1:3
                    colortemp(c).data=squeeze(app.histology_aligned_av_slices_RGB{app.section_nb}(:,:,c));
                    colortemp(c).data(paintmask) = rgb_color(c);
                    app.histology_aligned_av_slices_RGB{app.section_nb}(:,:,c) = colortemp(c).data;
                end
                colortemp=[];
            end
            % Delete previous ROI
            app.curr_roi = [];
            previous_roi = findobj(app.UIAxes,'Type','images.roi');
            delete(previous_roi)  
            
            app.ValidPaintingButton.BackgroundColor = [0.96 0.96 0.96];
            app.SavePaintingButton.BackgroundColor = [0 0.8 0];
            
            UpdateDisp(app, event)
        end

        % Button pushed function: SavePaintingButton
        function SavePainting(app, event)
            nb_sections = length(app.histology_ccf);
            nb_logs=length(app.histology_ccf_history);
            curr_log_nb = nb_logs +1;
            
            app.histology_ccf_history(curr_log_nb).history = app.histology_ccf;
            for s=1:nb_sections
                    tform = affine2d;
                    tform.T = app.histology_ccf_alignment{s};
                    % (transform is CCF -> histology, invert for other direction)
                    tform = invert(tform);
                
                    tform_size = imref2d([size(app.histology_ccf(s).av_slices,1), ...
                        size(app.histology_ccf(s).av_slices,2)]);
                    
                    app.histology_ccf(s).av_slices = ...
                        imwarp(app.histology_aligned_av_slices{s},tform,'nearest','OutputView',tform_size);
                    
                    
            end
            histology_ccf = app.histology_ccf;
            histology_ccf_history = app.histology_ccf_history;
            
            ccf_slice_fn = [app.MouseEditField.Value filesep 'histology_ccf.mat'];
            save(ccf_slice_fn,'histology_ccf','histology_ccf_history','-v7.3');
            app.SavePaintingButton.BackgroundColor =[0.96 0.96 0.96];

        end

        % Button pushed function: ExportButton
        function ExportImg(app, event)
            
%             s = app.section_nb
            if app.ExportAllSectionsButton.Value
                section_idx = [1:length(app.histology_ccf)];
                app.SectionListDropDown.Value=app.SectionListDropDown.Items{1};
                UpdateDisp(app,event)
            else
                section_idx = app.section_nb;
            end
            
            for s=1:length(section_idx)
                AllenImgPath = fullfile(app.MouseEditField.Value,strcat('CCF_',num2str(app.section_nb),'.tif'));
                BrainAtlImgPath = fullfile(app.MouseEditField.Value,strcat('Atlas_section_',num2str(app.section_nb),'.svg'));
                CCFAtlImgPath = fullfile(app.MouseEditField.Value,strcat('Atlas_CCF_',num2str(app.section_nb),'.svg'));

                if app.AllenalignedimagesCheckBox.Value
                    
                    % Compute inverse transform
                    tform = affine2d;
                    tform.T = app.histology_ccf_alignment{section_idx(s)};
                    % (transform is CCF -> histology, invert for other direction)
                    tform = invert(tform);
                
                    tform_size = imref2d([size(app.histology_ccf(section_idx(s)).av_slices,1), ...
                        size(app.histology_ccf(section_idx(s)).av_slices,2)]);
                    
                    % Compute Section image transform to CCF format
                    tempImg = imwarp(app.curr_img,tform,'nearest','OutputView',tform_size);
                    
                    H = figure('units','normalized','outerposition',[0 0 1 1]);
                    axes('Parent',H);
%                     image(gca,app.histology_ccf(section_idx(s)).av_slices)
                    image(gca,tempImg)

                    saveas(H,AllenImgPath)
                    close(H)
  
                end
                
                if app.BrainalignedatlasesCheckBox.Value
                    H = figure('units','normalized','outerposition',[0 0 1 1]);
                    H.Renderer = 'Painters';

                    image(gca,app.histology_aligned_av_slices_RGB{app.section_nb})
%                     exportgraphics(H,BrainAtlImgPath,'BackgroundColor','none','ContentType','vector','Colorspace','RGB') 
                    saveas(H,BrainAtlImgPath);

                    close(H)
                end
                
                if app.AllenalignedatlasesCheckBox.Value                
                    
                    rgb_CCF_atlas = av_to_RGB_fast(app.st,app.histology_ccf,app.section_nb);

                    H = figure('units','normalized','outerposition',[0 0 1 1]);
                    H.Renderer = 'Painters';

                    image(gca,rgb_CCF_atlas)
                    
%                     exportgraphics(H,CCFAtlImgPath,'BackgroundColor','none','ContentType','vector')
                    saveas(H,CCFAtlImgPath);
                    close(H)                
                end
                if section_idx(s) < section_idx(end)
                    
                    app.SectionListDropDown.Value = app.SectionListDropDown.Items{section_idx(s)+1};
                    UpdateDisp(app,event)
                end
            end
            
        end

        % Button pushed function: BrowseMiceButton
        function BrowseMice(app, event)
            [MiceDir]=uigetdir(matlabroot,'Select the Slice directory of AP_histology outputs for the mouse of interest');
            app.MouseEditField.Value = MiceDir;
        end

        % Button pushed function: BrowseAllenCCFButton
        function BrowseAllen(app, event)
            [AtlasDir]=uigetdir(matlabroot,'Select the directory where you stored the Allen Mouse Brain Atlas');
            app.AllenBrainEditField.Value = AtlasDir;
        end

        % Button pushed function: NextButton
        function Next(app, event)
            curr_s = str2double(app.SectionListDropDown.Value);
            if curr_s < length(app.SectionListDropDown.Items)
                app.SectionListDropDown.Value = app.SectionListDropDown.Items{curr_s+1};
                app.section_nb = curr_s+1;
            end
            UpdateDisp(app,event)
        end

        % Button pushed function: PreviousButton
        function Previous(app, event)
            curr_s = str2double(app.SectionListDropDown.Value);
            if curr_s > 1
                app.SectionListDropDown.Value = app.SectionListDropDown.Items{curr_s-1};
                app.section_nb = curr_s-1;

            end
            UpdateDisp(app,event)

        end

        % Button pushed function: DLSisolationButton
        function DLSisolation(app, event)
            
            Caudo_code = 574; % 574 is caudoputamen code (sphinx_id) in Allen Atlas
            DLS_code = 2; % Originally "Basic cell groups and regions" in the atlas. this nb will be assigned to the DLS at end of function;
            EllipseThicknessFactor = 6; % The bigger, the thinner the ellipse will be: EllipseThickness  = EllipseLength / EllipseThicknessFactor
%             ellipse_half_width = 50;
%             app.SectionListDropDown.Value =  app.SectionListDropDown.Items{s};
%             app.section_nb = 1;
            UpdateDisp(app, event);
            s=0;
            ewh = waitbar(0,strcat('Extracting results section:',num2str(s)));

            for s=1:length(app.histology_aligned_av_slices)
                waitbar(0,ewh,strcat('Extracting results section:',num2str(s)));

                app.section_nb = s;
                app.SectionListDropDown.Value =  app.SectionListDropDown.Items{s};

                UpdateDisp(app, event);

                if ~isempty(find(app.histology_aligned_av_slices{1, s} == Caudo_code))
                    
                    coords=[];
                    roi = [];
                    centerCoord = [];
                    Ellipse_mask = [];
                    coord_dors_point = [];                   
                    coord_lat_point = [];
                    
                    midlinecoord = round(size(app.histology_aligned_av_slices{1, s},2)/2);
                    
                    
                    %% Left side
                    half_brain_mask = ones(size(app.histology_aligned_av_slices{1, s}));
                    half_brain_mask(:,midlinecoord:end)=0;
                    half_brain_mask = logical(~half_brain_mask);
                    
                    unilat_atlas = app.histology_aligned_av_slices{1, s};
                    unilat_atlas(half_brain_mask)=0;
                    
                    [coords(:,1),coords(:,2)]=find(unilat_atlas == Caudo_code); % x and y of striatal pixels
        
                    striatal_lim_x = [min(coords(:,2)) max(coords(:,2))]; % min of x and y
                    striatal_lim_y = [min(coords(:,1)) max(coords(:,1))]; % max of x and y
                    
                    coord_dors_point = striatal_lim_x(1)+round((striatal_lim_x(2)-striatal_lim_x(1))*2/3); % 2/3 of x
                    
                    coord_lat_point = striatal_lim_y(1)+round((striatal_lim_y(2)-striatal_lim_y(1))*1/2); % 1/2 of y (because top of image index is 1, so upside down)
                  
                    
%                     % find complementary coordinates for 2/3 of the caudoputamen
                    [coord_dors_point(1,2),~] = find(unilat_atlas(:,coord_dors_point(1,1)) == Caudo_code,1,'first'); % Point A
                    [~,coord_lat_point(1,2)] = find(unilat_atlas(coord_lat_point(1,1),:) == Caudo_code,1,'first');  % Point B
                    
%                     coord_dors_point = flip(coord_dors_point);
                    coord_lat_point = flip(coord_lat_point);

                    
                    corner_coords = [coord_dors_point(1) coord_lat_point(2)]; % Point C
                    
                    
                    % Drawing ellipse
                    AB=[];
                    CB = [];
                    ang= [];
                    dist_lat_to_dors_points = [];
                    semiAxesLength = [];
                    
                    AB=abs(coord_lat_point - coord_dors_point);
                    CB=abs(corner_coords - coord_lat_point);
                    ang = atan2(abs(det([AB;CB])),dot(AB,CB));
                    ang = rad2deg(ang);
                    
                    dist_lat_to_dors_points = [abs(coord_lat_point(1)-coord_dors_point(1)) abs(coord_lat_point(2)-coord_dors_point(2)) ];
                    centerCoord = [coord_lat_point(1)+dist_lat_to_dors_points(1)/2 coord_lat_point(2)-dist_lat_to_dors_points(2)/2];
                    
                    semiAxesLength = sqrt((coord_lat_point(1)-coord_dors_point(1))^2+(coord_lat_point(2)-coord_dors_point(2))^2);
                    ellipse_half_width = semiAxesLength/EllipseThicknessFactor;
%                     angleBigAxe = 
                    roi = images.roi.Ellipse('Center',centerCoord,'SemiAxes',[semiAxesLength/2,ellipse_half_width],'RotationAngle',ang);
                    Ellipse_mask = createMask(roi,size(unilat_atlas,1),size(unilat_atlas,2));
                    
%                     % Drawing polygon to create DLS_mask based on the 2/3 coordinates
%                     x=[1  coord_dors_point(1) coord_dors_point(1) coord_lat_point(2) 1 1];
%                     y= [1  1 coord_dors_point(2) coord_lat_point(1) coord_lat_point(1)  1];
%                     
%                     corner_mask = poly2mask(x,y,size(unilat_atlas,1),size(unilat_atlas,2));
                    
                    Caudo_mask = unilat_atlas==Caudo_code;
                    DLS_mask = logical(Caudo_mask.*Ellipse_mask);
                    
                    % Writing on transformed atlas
                    app.histology_aligned_av_slices{1, s}(DLS_mask) = DLS_code;
%                     figure;imagesc(app.histology_aligned_av_slices{1, s})       
                    
                    %% Right side
                    coords=[];
                    roi = [];
                    centerCoord = [];
                    Ellipse_mask = [];
                    coord_dors_point = [];                   
                    coord_lat_point = [];
                    
                    half_brain_mask = ~half_brain_mask;
                    unilat_atlas = app.histology_aligned_av_slices{1, s};
                    unilat_atlas(half_brain_mask)=0;
                    
                    [coords(:,1),coords(:,2)]=find(unilat_atlas == Caudo_code); % x and y of striatal pixels
        
                    striatal_lim_x = [max(coords(:,2)) min(coords(:,2))]; % min of x and y
                    striatal_lim_y = [max(coords(:,1)) min(coords(:,1))]; % max of x and y
                    
                    coord_dors_point = striatal_lim_x(1)+round((striatal_lim_x(2)-striatal_lim_x(1))*2/3); % 2/3 of x
                    
                    coord_lat_point = striatal_lim_y(1)+round((striatal_lim_y(2)-striatal_lim_y(1))*1/2); % 1/2 of y (because top of image index is 1, so upside down)
                  
                    
%                     % find complementary coordinates for 2/3 of the caudoputamen
                    [coord_dors_point(1,2),~] = find(unilat_atlas(:,coord_dors_point(1,1)) == Caudo_code,1,'first'); % Point A
                    [~,coord_lat_point(1,2)] = find(unilat_atlas(coord_lat_point(1,1),:) == Caudo_code,1,'last');  % Point B
                    
%                     coord_dors_point = flip(coord_dors_point);
                    coord_lat_point = flip(coord_lat_point);

                    
                    corner_coords = [coord_dors_point(1) coord_lat_point(2)]; % Point C
                    
                    
                    % Drawing ellipse
                    AB=[];
                    CB = [];
                    ang= [];
                    dist_lat_to_dors_points = [];
                    semiAxesLength = [];
                    
                    AB=abs(coord_lat_point - coord_dors_point);
                    CB=abs(corner_coords - coord_lat_point);
                    ang = atan2(abs(det([AB;CB])),dot(AB,CB));
                    ang = rad2deg(ang);
                    
                    dist_lat_to_dors_points = [abs(coord_lat_point(1)-coord_dors_point(1)) abs(coord_lat_point(2)-coord_dors_point(2)) ];
                    centerCoord = [coord_lat_point(1)-dist_lat_to_dors_points(1)/2 coord_lat_point(2)-dist_lat_to_dors_points(2)/2];
                    
                    semiAxesLength = sqrt((coord_lat_point(1)-coord_dors_point(1))^2+(coord_lat_point(2)-coord_dors_point(2))^2);
                    
                    ellipse_half_width = semiAxesLength/EllipseThicknessFactor;
%                     angleBigAxe = 
                    roi = images.roi.Ellipse('Center',centerCoord,'SemiAxes',[semiAxesLength/2,ellipse_half_width],'RotationAngle',-ang);
                    Ellipse_mask = createMask(roi,size(unilat_atlas,1),size(unilat_atlas,2));
                    
%                     % Drawing polygon to create DLS_mask based on the 2/3 coordinates
%                     x=[1  coord_dors_point(1) coord_dors_point(1) coord_lat_point(2) 1 1];
%                     y= [1  1 coord_dors_point(2) coord_lat_point(1) coord_lat_point(1)  1];
%                     
%                     corner_mask = poly2mask(x,y,size(unilat_atlas,1),size(unilat_atlas,2));
                    
                    Caudo_mask = unilat_atlas==Caudo_code;
                    DLS_mask = logical(Caudo_mask.*Ellipse_mask);
                    
                    % Writing on transformed atlas
                    app.histology_aligned_av_slices{1, s}(DLS_mask) = DLS_code;
                    UpdateDisp(app,event)
                    
                else
                    app.SectionListDropDown.Value = aapp.SectionListDropDown.Items{s+1};
                end
            end % end for loop for sections
            close(ewh)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @mousehover, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @PickAreaOnClick, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AllenBrainEditFieldLabel = uilabel(app.UIFigure);
            app.AllenBrainEditFieldLabel.Text = 'Allen Brain';
            app.AllenBrainEditField = uieditfield(app.UIFigure, 'text');
            app.AllenBrainEditField.Value = 'C:\Users\phar0732\OneDrive - Nexus365\Documents\Allen Brain Mouse';
            app.BrowseAllenCCFButton = uibutton(app.UIFigure, 'push');
            app.BrowseAllenCCFButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseAllen, true);
            app.BrowseAllenCCFButton.Text = 'Browse Allen CCF';
            app.BrowseMiceButton = uibutton(app.UIFigure, 'push');
            app.BrowseMiceButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseMice, true);
            app.BrowseMiceButton.Text = 'Browse Mice';
            app.MouseEditFieldLabel = uilabel(app.UIFigure);
            app.MouseEditFieldLabel.Text = 'Mouse';
            app.MouseEditField = uieditfield(app.UIFigure, 'text');
            app.MouseEditField.Value = 'Z:\00 IMAGES\Dave full slides DiD and background only\317\slices';
            app.ColorknobLabel = uilabel(app.UIFigure);
            app.ColorknobLabel.Text = 'Color knob';
            app.ColorKnob = uiknob(app.UIFigure, 'discrete');
            app.ColorKnob.Items = {'All', 'Red', 'Green', 'Blue'};
            app.ColorKnob.ValueChangedFcn = createCallbackFcn(app, @UpdateDisp, true);
            app.ColorKnob.Value = 'All';
            app.SectionKnobLabel = uilabel(app.UIFigure);
            app.SectionKnobLabel.Text = 'Section';
            app.SectionAlphaKnob = uiknob(app.UIFigure, 'continuous');
            app.SectionAlphaKnob.ValueChangedFcn = createCallbackFcn(app, @UpdateDisp, true);
            app.SectionAlphaKnob.Value = 100;
            app.AtlasKnobLabel = uilabel(app.UIFigure);
            app.AtlasKnobLabel.Text = 'Atlas';
            app.AtlasAlphaKnob = uiknob(app.UIFigure, 'continuous');
            app.AtlasAlphaKnob.ValueChangedFcn = createCallbackFcn(app, @UpdateDisp, true);
            app.AtlasAlphaKnob.Value = 10;
            app.SectionListDropDownLabel = uilabel(app.UIFigure);
            app.SectionListDropDownLabel.Text = 'Section List';
            app.SectionListDropDown = uidropdown(app.UIFigure);
            app.SectionListDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadSection, true);
            app.AreasDropDownLabel = uilabel(app.UIFigure);
            app.AreasDropDownLabel.Text = 'Areas';
            app.AreasDropDown = uidropdown(app.UIFigure);
            app.AreasDropDown.ValueChangedFcn = createCallbackFcn(app, @PickAreaInMenu, true);
            app.DrawModeButtonGroup = uibuttongroup(app.UIFigure);
            app.DrawModeButtonGroup.Title = 'Draw Mode';
            app.FreeHandButton = uitogglebutton(app.DrawModeButtonGroup);
            app.FreeHandButton.Text = 'Free Hand';
            app.FreeHandButton.Value = true;
            app.EllipseButton = uitogglebutton(app.DrawModeButtonGroup);
            app.EllipseButton.Text = 'Ellipse';
            app.PolygonButton = uitogglebutton(app.DrawModeButtonGroup);
            app.PolygonButton.Text = 'Polygon';
            app.AssistHandButton = uitogglebutton(app.DrawModeButtonGroup);
            app.AssistHandButton.Text = 'Assist. Hand';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @Initialize, true);
            app.LoadButton.Text = 'Load';
            app.AreacolorButtonGroup = uibuttongroup(app.UIFigure);
            app.AreacolorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdateDisp, true);
            app.AreacolorButtonGroup.Title = 'Area color';
            app.AreacolorButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ConvertToAllenColors, true);
            app.FastButton = uiradiobutton(app.AreacolorButtonGroup);
            app.FastButton.Text = 'Fast';
            app.AllenButton = uiradiobutton(app.AreacolorButtonGroup);
            app.AllenButton.Text = 'Allen';
            app.BoundariesOnlyButton = uiradiobutton(app.AreacolorButtonGroup);
            app.BoundariesOnlyButton.Text = 'Boundaries Only';
            app.BoundariesOnlyButton.Value = true;
            app.ExtractValuesButtonGroup = uibuttongroup(app.UIFigure);
            app.ExtractValuesButtonGroup.Title = 'Extract Values';
            app.CurrentSectionButton = uiradiobutton(app.ExtractValuesButtonGroup);
            app.CurrentSectionButton.Text = 'Current Section';
            app.CurrentSectionButton.Value = true;
            app.AllSectionsButton = uiradiobutton(app.ExtractValuesButtonGroup);
            app.AllSectionsButton.Text = 'All Sections';
            app.ExtractButton = uibutton(app.ExtractValuesButtonGroup, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractFcn, true);
            app.ExtractButton.Text = 'Extract';
            app.ExporttoExcelCheckBox = uicheckbox(app.ExtractValuesButtonGroup);
            app.ExporttoExcelCheckBox.Text = 'Export to Excel';
            app.ExporttoExcelCheckBox.Value = true;
            app.ExporttoMatlabCheckBox = uicheckbox(app.ExtractValuesButtonGroup);
            app.ExporttoMatlabCheckBox.Text = 'Export to Matlab';
            app.ExporttoMatlabCheckBox.Value = true;
            app.ExporttoCSVCheckBox = uicheckbox(app.ExtractValuesButtonGroup);
            app.ExporttoCSVCheckBox.Text = 'Export to CSV';
            app.DLSisolationButton = uibutton(app.ExtractValuesButtonGroup, 'push');
            app.DLSisolationButton.ButtonPushedFcn = createCallbackFcn(app, @DLSisolation, true);
            app.DLSisolationButton.Text = 'DLS isolation';
            app.AreaTitle = uilabel(app.UIFigure);
            app.sphinx_idEditFieldLabel = uilabel(app.UIFigure);
            app.sphinx_idEditFieldLabel.Text = 'sphinx_id:';
            app.sphinx_idEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValidPaintingButton = uibutton(app.UIFigure, 'push');
            app.ValidPaintingButton.ButtonPushedFcn = createCallbackFcn(app, @ValidPainting, true);
            app.ValidPaintingButton.Text = 'Valid Painting';
            app.SavePaintingButton = uibutton(app.UIFigure, 'push');
            app.SavePaintingButton.ButtonPushedFcn = createCallbackFcn(app, @SavePainting, true);
            app.SavePaintingButton.Text = 'Save Painting';
            app.RedLabel = uilabel(app.UIFigure);
            app.RedLabel.Text = 'Red';
            app.RedRangeKnob = uiknob(app.UIFigure, 'continuous');
            app.RedRangeKnob.ValueChangedFcn = createCallbackFcn(app, @UpdateDisp, true);
            app.RedRangeKnob.Value = 70;
            app.OpacityLabel = uilabel(app.UIFigure);
            app.OpacityLabel.Text = 'Opacity';
            app.HistogramrangeLabel = uilabel(app.UIFigure);
            app.HistogramrangeLabel.Text = 'Histogram range';
            app.GreenLabel = uilabel(app.UIFigure);
            app.GreenLabel.Text = 'Green';
            app.GreenRangeKnob = uiknob(app.UIFigure, 'continuous');
            app.GreenRangeKnob.ValueChangedFcn = createCallbackFcn(app, @UpdateDisp, true);
            app.GreenRangeKnob.Value = 70;
            app.BlueLabel = uilabel(app.UIFigure);
            app.BlueLabel.Text = 'Blue';
            app.BlueRangeKnob = uiknob(app.UIFigure, 'continuous');
            app.BlueRangeKnob.ValueChangedFcn = createCallbackFcn(app, @UpdateDisp, true);
            app.BlueRangeKnob.Value = 70;
            app.AreaSelectionKnobLabel = uilabel(app.UIFigure);
            app.AreaSelectionKnobLabel.Text = 'Area Selection';
            app.AreaSelectionKnob = uiknob(app.UIFigure, 'discrete');
            app.AreaSelectionKnob.Items = {'All', 'Visible only', 'Custom'};
            app.AreaSelectionKnob.ValueChangedFcn = createCallbackFcn(app, @UpdateDisp, true);
            app.AreaSelectionKnob.Value = 'All';
            app.PaintButton = uibutton(app.UIFigure, 'push');
            app.PaintButton.ButtonPushedFcn = createCallbackFcn(app, @Paint, true);
            app.PaintButton.Text = 'Paint !';
            app.PressanykeyonthesectionimagetoselectanareaLabel = uilabel(app.UIFigure);
            app.PressanykeyonthesectionimagetoselectanareaLabel.Text = 'Press any key on the section image to select an area';
            app.ExportImagesButtonGroup = uibuttongroup(app.UIFigure);
            app.ExportImagesButtonGroup.Title = 'Export Images';
            app.ExportCurrentSectionButton = uiradiobutton(app.ExportImagesButtonGroup);
            app.ExportCurrentSectionButton.Text = 'Current Section';
            app.ExportCurrentSectionButton.Value = true;
            app.ExportAllSectionsButton = uiradiobutton(app.ExportImagesButtonGroup);
            app.ExportAllSectionsButton.Text = 'All sections';
            app.ExportButton = uibutton(app.ExportImagesButtonGroup, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImg, true);
            app.ExportButton.Text = 'Export';
            app.AllenalignedimagesCheckBox = uicheckbox(app.ExportImagesButtonGroup);
            app.AllenalignedimagesCheckBox.Text = 'Allen aligned images';
            app.AllenalignedimagesCheckBox.Value = true;
            app.AllenalignedatlasesCheckBox = uicheckbox(app.ExportImagesButtonGroup);
            app.AllenalignedatlasesCheckBox.Text = 'Allen aligned atlases';
            app.AllenalignedatlasesCheckBox.Value = true;
            app.BrainalignedatlasesCheckBox = uicheckbox(app.ExportImagesButtonGroup);
            app.BrainalignedatlasesCheckBox.Text = 'Brain aligned atlases';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @Previous, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @Next, true);
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AtlasPainter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @CreateAxeOnStartup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end